classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        TM5900Tab              matlab.ui.container.Tab
        ResumeButton           matlab.ui.control.Button
        zEditField_4           matlab.ui.control.NumericEditField
        zEditField_4Label      matlab.ui.control.Label
        yEditField_4           matlab.ui.control.NumericEditField
        yEditField_4Label      matlab.ui.control.Label
        xEditField_4           matlab.ui.control.NumericEditField
        xEditField_4Label      matlab.ui.control.Label
        zEditField_3           matlab.ui.control.NumericEditField
        zEditField_3Label      matlab.ui.control.Label
        yEditField_3           matlab.ui.control.NumericEditField
        yEditField_3Label      matlab.ui.control.Label
        xEditField_3           matlab.ui.control.NumericEditField
        xEditField_3Label      matlab.ui.control.Label
        PlaceLocationLabel_2   matlab.ui.control.Label
        PickLocationLabel_2    matlab.ui.control.Label
        TeachButton_7          matlab.ui.control.Button
        StatusLamp             matlab.ui.control.Lamp
        StatusLampLabel        matlab.ui.control.Label
        Joint3Slider_5         matlab.ui.control.Slider
        Joint3Slider_5Label    matlab.ui.control.Label
        EditField_6            matlab.ui.control.NumericEditField
        EditField_5            matlab.ui.control.NumericEditField
        EditField_4            matlab.ui.control.NumericEditField
        EditField_3            matlab.ui.control.NumericEditField
        EditField_2            matlab.ui.control.NumericEditField
        EditField              matlab.ui.control.NumericEditField
        EStopButton_2          matlab.ui.control.Button
        Joint6Slider_3         matlab.ui.control.Slider
        Joint6Slider_3Label    matlab.ui.control.Label
        Joint5Slider_3         matlab.ui.control.Slider
        Joint5Slider_3Label    matlab.ui.control.Label
        Joint4Slider_3         matlab.ui.control.Slider
        Joint4Slider_3Label    matlab.ui.control.Label
        Joint2Slider_3         matlab.ui.control.Slider
        Joint2Slider_3Label    matlab.ui.control.Label
        Joint1Slider_3         matlab.ui.control.Slider
        Joint1Slider_3Label    matlab.ui.control.Label
        HomeButton_6           matlab.ui.control.Button
        TM5900ControlLabel     matlab.ui.control.Label
        UR3Tab                 matlab.ui.container.Tab
        ResumeButton_2         matlab.ui.control.Button
        zEditField_6           matlab.ui.control.NumericEditField
        zEditField_6Label      matlab.ui.control.Label
        yEditField_6           matlab.ui.control.NumericEditField
        yEditField_6Label      matlab.ui.control.Label
        xEditField_6           matlab.ui.control.NumericEditField
        xEditField_6Label      matlab.ui.control.Label
        zEditField_5           matlab.ui.control.NumericEditField
        zEditField_5Label      matlab.ui.control.Label
        yEditField_5           matlab.ui.control.NumericEditField
        yEditField_5Label      matlab.ui.control.Label
        xEditField_5           matlab.ui.control.NumericEditField
        xEditField_5Label      matlab.ui.control.Label
        PlaceLocationLabel_3   matlab.ui.control.Label
        PickLocationLabel_3    matlab.ui.control.Label
        TeachButton_8          matlab.ui.control.Button
        StatusLamp_2           matlab.ui.control.Lamp
        StatusLamp_2Label      matlab.ui.control.Label
        Joint6Slider_4         matlab.ui.control.Slider
        Joint6Slider_4Label    matlab.ui.control.Label
        EditField_15           matlab.ui.control.NumericEditField
        Joint2Slider_4         matlab.ui.control.Slider
        Joint2Slider_4Label    matlab.ui.control.Label
        Joint5Slider_4         matlab.ui.control.Slider
        Joint5Slider_4Label    matlab.ui.control.Label
        Joint4Slider_4         matlab.ui.control.Slider
        Joint4Slider_4Label    matlab.ui.control.Label
        Joint3Slider_6         matlab.ui.control.Slider
        Joint3Slider_6Label    matlab.ui.control.Label
        Joint1Slider_5         matlab.ui.control.Slider
        Joint1Slider_5Label    matlab.ui.control.Label
        RailSlider             matlab.ui.control.Slider
        RailSliderLabel        matlab.ui.control.Label
        EditField_13           matlab.ui.control.NumericEditField
        EditField_12           matlab.ui.control.NumericEditField
        EditField_11           matlab.ui.control.NumericEditField
        EditField_10           matlab.ui.control.NumericEditField
        EditField_9            matlab.ui.control.NumericEditField
        EditField_8            matlab.ui.control.NumericEditField
        EStopButton            matlab.ui.control.Button
        HomeButton_7           matlab.ui.control.Button
        UR3ControlLabel        matlab.ui.control.Label
        ControlTab             matlab.ui.container.Tab
        StatusLamp_3           matlab.ui.control.Lamp
        StatusLamp_3Label      matlab.ui.control.Label
        ResumeButton_3         matlab.ui.control.Button
        ESTOPButton            matlab.ui.control.StateButton
        STARTSIMULATIONButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        % Robot1 = TM5();
%         robotBaseTransform = transl(5, 5, 0);
        % Robot2 = LinearUR3();
        counter = 0;
    end
    
    methods (Access = private)
        
        function start(app)
%% RED TEST TUBE RACK
REDSORTEDTTR = testTubeRack(transl(-0.9,-0.1,0.62)*trotz(deg2rad(90)));

REDlocation1 = REDSORTEDTTR.model.base*transl(transl(-0.025,-0.119, 0.13));

REDlocation2 = REDSORTEDTTR.model.base*transl(transl(-0.025,-0.197,0.13));

REDlocation3 = REDSORTEDTTR.model.base*transl(transl(-0.025,-0.274,0.13));

%% GREEN TEST TUBE RACK
GREENSORTEDTTR = testTubeRack(transl(-0.7,-0.8,0.62)*trotz(deg2rad(90)));

GREENlocation1 = GREENSORTEDTTR.model.base*transl(transl(-0.025,-0.119, 0.13));

GREENlocation2 = GREENSORTEDTTR.model.base*transl(transl(-0.025,-0.197,0.13));

GREENlocation3 = GREENSORTEDTTR.model.base*transl(transl(-0.025,-0.274,0.13));

%% BLUE TEST TUBE RACK
BLUESORTEDTTR = testTubeRack(transl(-0.4,-0.8,0.62)*trotz(deg2rad(90)));

BLUElocation1 = BLUESORTEDTTR.model.base*transl(transl(-0.025,-0.119, 0.13));

BLUElocation2 = BLUESORTEDTTR.model.base*transl(transl(-0.025,-0.197,0.13));

BLUElocation3 = BLUESORTEDTTR.model.base*transl(transl(-0.025,-0.274,0.13));


TTF1 = testTubeRackFilled(transl(0.13,-2.08,0.72));
%% Placement of LUR3 robot
fprintf('Creating LUR3\n');
baseLUR3 = transl([0,-0.5,0.7])*trotz(0);
lur3 = LinearUR3(baseLUR3);
lur3startpos = [-0.0100         0         0         0         0   -1.5708         0];
lur3.model.animate(lur3startpos);
PlaceObject('emergencyStopWallMounted.ply', [0.8,1.8,1]);

%% Placement of TM5-900 robot
fprintf('Creating TM5-900\n');
baseTM5 = transl([-0.3,-1.5,0.7])*trotz(-pi);
tm5 = TM5(baseTM5);
tm5startpos = [1.5708   -1.5708    1.5708   -1.5708   -1.5708    3.1416];
tm5.model.animate(tm5startpos);
fprintf('RETRIEVING SAMPLES...\n')

%% TM5 MOVES FROM START POS TO TEST TUBE RACK WITH ASSORTED TEST TUBES
steps = 100;
tm5grabrack = [2.3562   -2.1817   -1.4600   -1.0223    1.5708    4.1233];
movefrominitialtorandTT = jtraj(tm5.model.getpos(),tm5grabrack, steps);
for i = 1:length(movefrominitialtorandTT)
    
    tm5.model.animate(movefrominitialtorandTT(i,:))
        drawnow();
end

%% TM5 MOVES ASSORTED TEST TUBE RACK TO SORTING START POS
TTFsortstartlocation = [-0.8602   -2.0071   -1.7453   -0.8727    1.6955    4.1233];

movefromTTtostartpos = jtraj(tm5.model.getpos(), TTFsortstartlocation, steps);

for i = 1:length(movefromTTtostartpos)    
    tm5.model.animate(movefromTTtostartpos(i,:))

        TM5pos = transl(transl(tm5.model.fkineUTS(tm5.model.getpos())))*transl(0,0,-0.2);
        flip = trotx(0,'deg');
        TTF1.model.base = TM5pos*flip;
        TTF1.model.animate(0);
        drawnow();
end

TTRE = testTubeRackEmpty(transl(transl(TTF1.model.base)));

%% TEST TUBE LOCATIONS

R1o = TTRE.model.base*transl(transl(-0.176,0.164, 0.02));
R2o = TTRE.model.base*transl(transl(-0.113,0.164,0.02));
R3o = TTRE.model.base*transl(transl(-0.046,0.104,0.02));

G1o = TTRE.model.base*transl(transl(-0.046,0.164,0.02));
G2o = TTRE.model.base*transl(transl(-0.113,0.104,0.02));
G3o = TTRE.model.base*transl(transl(-0.046, 0.041,0.02));

B1o = TTRE.model.base*transl(transl(-0.176,0.104,0.02));
B2o = TTRE.model.base*transl(transl(-0.176,0.041,0.02));
B3o = TTRE.model.base*transl(transl(-0.113, 0.041,0.02));

%% Create Test Tubes
R1 = testTubeR(transl(R1o));
R2 = testTubeR(transl(R2o));
R3 = testTubeR(transl(R3o));

G1 = testTubeG(transl(G1o));
G2 = testTubeG(transl(G2o));
G3 = testTubeG(transl(G3o));

B1 = testTubeB(transl(B1o));
B2 = testTubeB(transl(B2o));
B3 = testTubeB(transl(B3o));

clear TTF1;

%% TM5 moves up to see all test tubes
endpos = [1.5708   -1.3334    0.7854   -1.0228   -1.0472    1.5708];
tm5endtraj = jtraj(tm5.model.getpos(), endpos, steps);
for i = 1:length(tm5endtraj)   
    tm5.model.animate(tm5endtraj(i,:))
        drawnow();
end

%% LUR3 MOVES R1
fprintf('Sorting R1\n');
lur3R1 = (transl(transl(R1.model.base))*transl(0,0,0.13))*trotx(deg2rad(180));
lur3toR1 = lur3.model.ikcon(lur3R1,lur3.model.getpos());

r1starttraj = jtraj(lur3.model.getpos(), lur3toR1, steps);
for i = 1:length(r1starttraj)
    lur3.model.animate(r1starttraj(i,:))
        drawnow();
end

RaiseRobot(lur3, lur3.model.getpos(),R1);
Movetolocation(lur3,REDlocation1,R1);
LowerRobot(lur3,lur3.model.getpos(),R1)


%% LUR3 MOVES R2
fprintf('Sorting R2\n');
Movetolocation(lur3, transl(R2.model.base))
RaiseRobot(lur3, lur3.model.getpos(),R2)
Movetolocation(lur3,REDlocation2,R2)
LowerRobot(lur3,lur3.model.getpos(),R2)
RaiseRobot(lur3,lur3.model.getpos());

%% LUR3 MOVES R3
fprintf('Sorting R3\n');
Movetolocation(lur3, transl(R3.model.base))
RaiseRobot(lur3, lur3.model.getpos(),R3)
Movetolocation(lur3,REDlocation3,R3)
LowerRobot(lur3,lur3.model.getpos(),R3)
RaiseRobot(lur3,lur3.model.getpos());

lur3sortstart = [-0.6840         0   -0.0873   -1.8132    0.3491    1.5708         0];

sortstarttraj = jtraj(lur3.model.getpos(), lur3sortstart, steps);
for i = 1:length(sortstarttraj)
    lur3.model.animate(sortstarttraj(i,:))
        drawnow();
end

%% LUR3 MOVES G1
fprintf('Sorting G1\n');
Movetolocation(lur3, transl(G1.model.base))
RaiseRobot(lur3, lur3.model.getpos(),G1)
Movetolocation(lur3,GREENlocation1,G1)
LowerRobot(lur3,lur3.model.getpos(),G1)
RaiseRobot(lur3,lur3.model.getpos());

%% LUR3 MOVES G2
fprintf('Sorting G2\n');
Movetolocation(lur3, transl(G2.model.base))
RaiseRobot(lur3, lur3.model.getpos(),G2)
Movetolocation(lur3,GREENlocation2,G2)
LowerRobot(lur3,lur3.model.getpos(),G2)
RaiseRobot(lur3,lur3.model.getpos());

%% LUR3 MOVES G3
fprintf('Sorting G3\n');
Movetolocation(lur3, transl(G3.model.base))
RaiseRobot(lur3, lur3.model.getpos(),G3)
Movetolocation(lur3,GREENlocation3,G3)
LowerRobot(lur3,lur3.model.getpos(),G3)
RaiseRobot(lur3,lur3.model.getpos());

%% LUR3 MOVES B1
fprintf('Sorting B1\n');
Movetolocation(lur3, transl(B1.model.base))
RaiseRobot(lur3, lur3.model.getpos(),B1)
Movetolocation(lur3,BLUElocation1,B1)
LowerRobot(lur3,lur3.model.getpos(),B1)
RaiseRobot(lur3,lur3.model.getpos());

%% LUR3 MOVES B2
fprintf('Sorting B2\n');
Movetolocation(lur3, transl(B2.model.base))
RaiseRobot(lur3, lur3.model.getpos(),B2)
Movetolocation(lur3,BLUElocation2,B2)
LowerRobot(lur3,lur3.model.getpos(),B2)
RaiseRobot(lur3,lur3.model.getpos());

%% LUR3 MOVES B3
fprintf('Sorting B3\n');
Movetolocation(lur3, transl(B3.model.base))
RaiseRobot(lur3, lur3.model.getpos(),B3)
Movetolocation(lur3,BLUElocation3,B3)
LowerRobot(lur3,lur3.model.getpos(),B3)
RaiseRobot(lur3,lur3.model.getpos());

fprintf('Sorting Completed!')

app.counter = 0;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                         
fprintf('Initialising Environment...\n');
hold on
%set environment axis
axis([-4, 4, -4, 4, 0, 4]);

%% SAFETY OBJECTS
%Placement of floor, brickwalls, a light curtain 
% and an emergency stop button
PlaceObject('floor.ply',[0,-1,0.01]);
PlaceObject('brickWallX.ply',[3,2,0]);
PlaceObject('brickWallLeft.ply',[-3,2,0]);
PlaceObject('brickWallRight.ply',[3,-4,0]);
PlaceObject('wallDoor.ply',[0.7,-4,0]);

PlaceObject('fireExtinguisherElevated.ply', [-2.5,1.5,0.45]);
PlaceObject('fireBlanket.ply', [-2,1.7,0.7]);
% PlaceObject('firstAidKit.ply', [-1.2,1.6,0.7]);
PlaceObject('lightCurtain.ply', [0.5,1,0]);
PlaceObject('lightCurtain2.ply', [-2.7,1,0]);
PlaceObject('lightCurtain3.ply', [0.5,-3.5,0]);
PlaceObject('lightCurtain4.ply', [-2.7,-3.5,0]);
PlaceObject('lightCurtain5.ply', [0.5,0.8,0]);
PlaceObject('lightCurtain6.ply', [0.5,-3.3,0]);
PlaceObject('cardReader.ply', [0.7,-4,0.6]);

%Placement of the two lab tables
PlaceObject('LabTable.ply', [0.2,0.2,0]);
PlaceObject('LabTable.ply', [0.2,-1.4,0]);
        end

        % Value changed function: Joint1Slider_3
        function Joint1Slider_3ValueChanged(app, event)
            value = app.Joint1Slider_3.Value;
            app.EditField.Value = value;
            currentJointPositions = app.Robot1.model.getpos();
            currentJointPositions(1) = deg2rad(value);
            app.Robot1.model.animate(currentJointPositions);
        end

        % Value changed function: Joint2Slider_3
        function Joint2Slider_3ValueChanged(app, event)
            value = app.Joint2Slider_3.Value;
            app.EditField_2.Value = value;
            currentJointPositions = app.Robot1.model.getpos();
            currentJointPositions(2) = deg2rad(value);
            app.Robot1.model.animate(currentJointPositions);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.Joint1Slider_3.Value = value;
            currentJointPositions = app.Robot1.model.getpos();
            currentJointPositions(1) = deg2rad(value);
            app.Robot1.model.animate(currentJointPositions);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.Joint2Slider_3.Value = value;
            currentJointPositions = app.Robot1.model.getpos();
            currentJointPositions(2) = deg2rad(value);
            app.Robot1.model.animate(currentJointPositions);
        end

        % Value changed function: Joint3Slider_5
        function Joint3Slider_5ValueChanged(app, event)
            value = app.Joint3Slider_5.Value;
            app.EditField_3.Value = value;
            currentJointPositions = app.Robot1.model.getpos();
            currentJointPositions(3) = deg2rad(value);
            app.Robot1.model.animate(currentJointPositions);
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            app.Joint3Slider_5.Value = value;
            currentJointPositions = app.Robot1.model.getpos();
            currentJointPositions(3) = deg2rad(value);
            app.Robot1.model.animate(currentJointPositions);
        end

        % Value changed function: Joint4Slider_3
        function Joint4Slider_3ValueChanged(app, event)
            value = app.Joint4Slider_3.Value;
            app.EditField_4.Value = value;
            currentJointPositions = app.Robot1.model.getpos();
            currentJointPositions(4) = deg2rad(value);
            app.Robot1.model.animate(currentJointPositions);
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            app.Joint4Slider_3.Value = value;
            currentJointPositions = app.Robot1.model.getpos();
            currentJointPositions(4) = deg2rad(value);
            app.Robot1.model.animate(currentJointPositions);
        end

        % Value changed function: Joint5Slider_3
        function Joint5Slider_3ValueChanged(app, event)
            value = app.Joint5Slider_3.Value;
            app.EditField_5.Value = value;
            currentJointPositions = app.Robot1.model.getpos();
            currentJointPositions(5) = deg2rad(value);
            app.Robot1.model.animate(currentJointPositions);
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
            app.Joint5Slider_3.Value = value;
            currentJointPositions = app.Robot1.model.getpos();
            currentJointPositions(5) = deg2rad(value);
            app.Robot1.model.animate(currentJointPositions);
        end

        % Value changed function: Joint6Slider_3
        function Joint6Slider_3ValueChanged(app, event)
            value = app.Joint6Slider_3.Value;
            app.EditField_6.Value = value;
            currentJointPositions = app.Robot1.model.getpos();
            currentJointPositions(6) = deg2rad(value);
            app.Robot1.model.animate(currentJointPositions);
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            value = app.EditField_6.Value;
            app.Joint6Slider_3.Value = value;
            currentJointPositions = app.Robot1.model.getpos();
            currentJointPositions(6) = deg2rad(value);
            app.Robot1.model.animate(currentJointPositions);
        end

        % Callback function
        function GripperSlider_3ValueChanged(app, event)

        end

        % Callback function
        function EditField_7ValueChanged(app, event)

        end

        % Button pushed function: HomeButton_6
        function HomeButton_6Pushed(app, event)
            % Set all joint positions to 0 degrees (or your desired home position)
            homeJointPositions = deg2rad([0, 0, 0, 0, 0, 0]);
            
            % Animate the robot to the home position
            app.Robot1.model.animate(homeJointPositions);
            
            % Update the Edit Fields and Sliders with the home position values
            app.EditField.Value = rad2deg(homeJointPositions(1));
            app.Joint1Slider_3.Value = rad2deg(homeJointPositions(1));
            app.EditField_2.Value = rad2deg(homeJointPositions(2));
            app.Joint2Slider_3.Value = rad2deg(homeJointPositions(2));
            app.EditField_3.Value = rad2deg(homeJointPositions(3));
            app.Joint3Slider_5.Value = rad2deg(homeJointPositions(3));
            app.EditField_4.Value = rad2deg(homeJointPositions(4));
            app.Joint4Slider_3.Value = rad2deg(homeJointPositions(4));
            app.EditField_5.Value = rad2deg(homeJointPositions(5));
            app.Joint5Slider_3.Value = rad2deg(homeJointPositions(5));
            app.EditField_6.Value = rad2deg(homeJointPositions(6));
            app.Joint6Slider_3.Value = rad2deg(homeJointPositions(6));
        end

        % Button pushed function: EStopButton_2
        function EStopButton_2Pushed(app, event)
            app.StatusLamp.Color = 'red';
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            app.StatusLamp.Color = 'green';
        end

        % Value changed function: RailSlider
        function RailSliderValueChanged(app, event)
            value = app.RailSlider.Value;
            app.EditField_8.Value = value;
            currentJointPositions = app.Robot2.model.getpos();
            currentJointPositions(1) = deg2rad(value);
            app.Robot2.model.animate(currentJointPositions);
        end

        % Value changed function: EditField_8
        function EditField_8ValueChanged(app, event)
            value = app.EditField_8.Value;
            app.RailSlider.Value = value;
            currentJointPositions = app.Robot2.model.getpos();
            currentJointPositions(1) = deg2rad(value);
            app.Robot2.model.animate(currentJointPositions);
        end

        % Value changed function: Joint1Slider_5
        function Joint1Slider_5ValueChanged(app, event)
            value = app.Joint1Slider_5.Value;
            app.EditField_9.Value = value;
            currentJointPositions = app.Robot2.model.getpos();
            currentJointPositions(2) = deg2rad(value);
            app.Robot2.model.animate(currentJointPositions);
        end

        % Value changed function: EditField_9
        function EditField_9ValueChanged(app, event)
            value = app.EditField_9.Value;
            app.Joint1Slider_5.Value = value;
            currentJointPositions = app.Robot2.model.getpos();
            currentJointPositions(2) = deg2rad(value);
            app.Robot2.model.animate(currentJointPositions);
        end

        % Value changed function: Joint2Slider_4
        function Joint2Slider_4ValueChanged(app, event)
            value = app.Joint2Slider_4.Value;
            app.EditField_10.Value = value;
            currentJointPositions = app.Robot2.model.getpos();
            currentJointPositions(3) = deg2rad(value);
            app.Robot2.model.animate(currentJointPositions);
        end

        % Value changed function: EditField_10
        function EditField_10ValueChanged(app, event)
            value = app.EditField_10.Value;
            app.Joint2Slider_4.Value = value;
            currentJointPositions = app.Robot2.model.getpos();
            currentJointPositions(3) = deg2rad(value);
            app.Robot2.model.animate(currentJointPositions);
        end

        % Value changed function: Joint3Slider_6
        function Joint3Slider_6ValueChanged(app, event)
            value = app.Joint3Slider_6.Value;
            app.EditField_11.Value = value;
            currentJointPositions = app.Robot2.model.getpos();
            currentJointPositions(4) = deg2rad(value);
            app.Robot2.model.animate(currentJointPositions);
        end

        % Value changed function: EditField_11
        function EditField_11ValueChanged(app, event)
            value = app.EditField_11.Value;
            app.Joint3Slider_6.Value = value;
            currentJointPositions = app.Robot2.model.getpos();
            currentJointPositions(4) = deg2rad(value);
            app.Robot2.model.animate(currentJointPositions);
        end

        % Value changed function: Joint4Slider_4
        function Joint4Slider_4ValueChanged(app, event)
            value = app.Joint4Slider_4.Value;
            app.EditField_12.Value = value;
            currentJointPositions = app.Robot2.model.getpos();
            currentJointPositions(5) = deg2rad(value);
            app.Robot2.model.animate(currentJointPositions);
        end

        % Value changed function: EditField_12
        function EditField_12ValueChanged(app, event)
            value = app.EditField_12.Value;
            app.Joint4Slider_4.Value = value;
            currentJointPositions = app.Robot2.model.getpos();
            currentJointPositions(5) = deg2rad(value);
            app.Robot2.model.animate(currentJointPositions);
        end

        % Value changed function: Joint5Slider_4
        function Joint5Slider_4ValueChanged(app, event)
            value = app.Joint5Slider_4.Value;
            app.EditField_13.Value = value;
            currentJointPositions = app.Robot2.model.getpos();
            currentJointPositions(6) = deg2rad(value);
            app.Robot2.model.animate(currentJointPositions);
        end

        % Value changed function: EditField_13
        function EditField_13ValueChanged(app, event)
            value = app.EditField_13.Value;
            app.Joint5Slider_4.Value = value;
            currentJointPositions = app.Robot2.model.getpos();
            currentJointPositions(6) = deg2rad(value);
            app.Robot2.model.animate(currentJointPositions);
        end

        % Value changed function: Joint6Slider_4
        function Joint6Slider_4ValueChanged(app, event)
            value = app.Joint6Slider_4.Value;
            app.EditField_15.Value = value;
            currentJointPositions = app.Robot2.model.getpos();
            currentJointPositions(7) = deg2rad(value);
            app.Robot2.model.animate(currentJointPositions);
        end

        % Value changed function: EditField_15
        function EditField_15ValueChanged(app, event)
            value = app.EditField_15.Value;
            app.Joint6Slider_4.Value = value;
            currentJointPositions = app.Robot2.model.getpos();
            currentJointPositions(7) = deg2rad(value);
            app.Robot2.model.animate(currentJointPositions);
        end

        % Callback function
        function GripperSlider_4ValueChanged(app, event)

        end

        % Callback function
        function EditField_14ValueChanged(app, event)

        end

        % Button pushed function: HomeButton_7
        function HomeButton_7Pushed(app, event)
            % Set all joint positions to 0 degrees (or your desired home position)
            homeJointPositions = deg2rad([-0.01, 0, 0, 0, 0, 0, 0]);
            
            % Animate the robot to the home position
            app.Robot2.model.animate(homeJointPositions);
            
            % Update the Edit Fields and Sliders with the home position values
            app.EditField_8.Value = rad2deg(homeJointPositions(1));
            app.RailSlider.Value = rad2deg(homeJointPositions(1));
            app.EditField_9.Value = rad2deg(homeJointPositions(2));
            app.Joint1Slider_5.Value = rad2deg(homeJointPositions(2));
            app.EditField_10.Value = rad2deg(homeJointPositions(3));
            app.Joint2Slider_4.Value = rad2deg(homeJointPositions(3));
            app.EditField_11.Value = rad2deg(homeJointPositions(4));
            app.Joint3Slider_6.Value = rad2deg(homeJointPositions(4));
            app.EditField_12.Value = rad2deg(homeJointPositions(5));
            app.Joint4Slider_4.Value = rad2deg(homeJointPositions(5));
            app.EditField_13.Value = rad2deg(homeJointPositions(6));
            app.Joint5Slider_4.Value = rad2deg(homeJointPositions(6));
            app.EditField_15.Value = rad2deg(homeJointPositions(7));
            app.Joint6Slider_4.Value = rad2deg(homeJointPositions(7));
        end

        % Button pushed function: EStopButton
        function EStopButtonPushed(app, event)
            app.StatusLamp_2.Color = 'red';
        end

        % Button pushed function: ResumeButton_2
        function ResumeButton_2Pushed(app, event)
            app.StatusLamp_2.Color = 'green';
        end

        % Button pushed function: TeachButton_7
        function TeachButton_7Pushed(app, event)
            % Get the X, Y, and Z values for the pick and place locations
            pickX = app.xEditField_3.Value;
            pickY = app.yEditField_3.Value;
            pickZ = app.zEditField_3.Value;
            placeX = app.xEditField_4.Value;
            placeY = app.yEditField_4.Value;
            placeZ = app.zEditField_4.Value;
        
            % Define the pick and place poses
            pickPose = transl(pickX, pickY, pickZ);
            placePose = transl(placeX, placeY, placeZ);
        
            % Get the current joint positions of the robot
            q_now = app.Robot1.model.getpos();
        
            % Calculate the target joint positions for pick and place poses
            q_pick = app.Robot1.model.ikcon(pickPose, q_now);
            q_place = app.Robot1.model.ikcon(placePose, q_pick);
        
            % Calculate a trajectory between the pick and place positions
            q_traj = jtraj(q_pick, q_place, 40);
        
            % Animate the robot along the calculated trajectory
            app.Robot1.model.animate(q_traj);
        end

        % Callback function
        function TeachButton_9Pushed(app, event)

        end

        % Button down function: TM5900Tab
        function TM5900TabButtonDown(app, event)
            
        end

        % Button pushed function: TeachButton_8
        function TeachButton_8Pushed(app, event)
            % Get the X, Y, and Z values for the pick and place locations
            pickX = app.xEditField_5.Value;
            pickY = app.yEditField_5.Value;
            pickZ = app.zEditField_5.Value;
            placeX = app.xEditField_6.Value;
            placeY = app.yEditField_6.Value;
            placeZ = app.zEditField_6.Value;
        
            % Define the pick and place poses
            pickPose = transl(pickX, pickY, pickZ);
            placePose = transl(placeX, placeY, placeZ);
        
            % Get the current joint positions of the robot
            q_now = app.Robot2.model.getpos();
        
            % Calculate the target joint positions for pick and place poses
            q_pick = app.Robot2.model.ikcon(pickPose, q_now);
            q_place = app.Robot2.model.ikcon(placePose, q_pick);
        
            % Calculate a trajectory between the pick and place positions
            q_traj = jtraj(q_pick, q_place, 40);
        
            % Animate the robot along the calculated trajectory
            app.Robot2.model.animate(q_traj);
        end

        % Button pushed function: STARTSIMULATIONButton
        function STARTSIMULATIONButtonPushed(app, event)
        if app.counter > 0
            disp('Simulation already started!')
        else
            disp('Starting Simulation...');
            % Update status lamp
            app.counter = app.counter + 1;
            app.StatusLamp_3.Color = [0,1,0];
            start(app);
        end
        end

        % Value changed function: ESTOPButton
        function ESTOPButtonValueChanged(app, event)
            value = app.ESTOPButton.Value;
             if value == 1
                disp('E-STOP button is pressed!');
                app.StatusLamp_3.Color = [1, 0, 0];
                pause();
            else
                disp('E-STOP button is released!');
                app.StatusLamp_3.Color = [1, 1, 0];
             end
        end

        % Button pushed function: ResumeButton_3
        function ResumeButton_3Pushed(app, event)
            if app.ESTOPButton.Value == 0
            % Resume the simulation
            disp('Resuming simulation...');
            % Update state and status lamp
            app.StatusLamp_3.Color = [0, 1, 0];
            % Add code to resume the simulation after E-STOP
            end

            disp('Simulation has not been stopped!')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TM5900Tab = uitab(app.TabGroup);
            app.TM5900Tab.Title = 'TM5-900';
            app.TM5900Tab.ButtonDownFcn = createCallbackFcn(app, @TM5900TabButtonDown, true);
            app.TM5900ControlLabel = uilabel(app.TM5900Tab);
            app.TM5900ControlLabel.Text = 'TM5-900 Control';
            app.HomeButton_6 = uibutton(app.TM5900Tab, 'push');
            app.HomeButton_6.ButtonPushedFcn = createCallbackFcn(app, @HomeButton_6Pushed, true);
            app.HomeButton_6.Text = 'Home';
            app.Joint1Slider_3Label = uilabel(app.TM5900Tab);
            app.Joint1Slider_3Label.Text = 'Joint 1';
            app.Joint1Slider_3 = uislider(app.TM5900Tab);
            app.Joint1Slider_3.Limits = [-270 270];
            app.Joint1Slider_3.ValueChangedFcn = createCallbackFcn(app, @Joint1Slider_3ValueChanged, true);
            app.Joint2Slider_3Label = uilabel(app.TM5900Tab);
            app.Joint2Slider_3Label.Text = 'Joint 2';
            app.Joint2Slider_3 = uislider(app.TM5900Tab);
            app.Joint2Slider_3.Limits = [-180 180];
            app.Joint2Slider_3.ValueChangedFcn = createCallbackFcn(app, @Joint2Slider_3ValueChanged, true);
            app.Joint4Slider_3Label = uilabel(app.TM5900Tab);
            app.Joint4Slider_3Label.Text = 'Joint 4';
            app.Joint4Slider_3 = uislider(app.TM5900Tab);
            app.Joint4Slider_3.Limits = [-180 180];
            app.Joint4Slider_3.ValueChangedFcn = createCallbackFcn(app, @Joint4Slider_3ValueChanged, true);
            app.Joint5Slider_3Label = uilabel(app.TM5900Tab);
            app.Joint5Slider_3Label.Text = 'Joint 5';
            app.Joint5Slider_3 = uislider(app.TM5900Tab);
            app.Joint5Slider_3.Limits = [-180 180];
            app.Joint5Slider_3.ValueChangedFcn = createCallbackFcn(app, @Joint5Slider_3ValueChanged, true);
            app.Joint6Slider_3Label = uilabel(app.TM5900Tab);
            app.Joint6Slider_3Label.Text = 'Joint 6';
            app.Joint6Slider_3 = uislider(app.TM5900Tab);
            app.Joint6Slider_3.Limits = [-270 270];
            app.Joint6Slider_3.ValueChangedFcn = createCallbackFcn(app, @Joint6Slider_3ValueChanged, true);
            app.EStopButton_2 = uibutton(app.TM5900Tab, 'push');
            app.EStopButton_2.ButtonPushedFcn = createCallbackFcn(app, @EStopButton_2Pushed, true);
            app.EStopButton_2.Text = 'E-Stop';
            app.EditField = uieditfield(app.TM5900Tab, 'numeric');
            app.EditField.Limits = [-270 270];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.TM5900Tab, 'numeric');
            app.EditField_2.Limits = [-180 180];
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3 = uieditfield(app.TM5900Tab, 'numeric');
            app.EditField_3.Limits = [-155 155];
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_4 = uieditfield(app.TM5900Tab, 'numeric');
            app.EditField_4.Limits = [-180 180];
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_5 = uieditfield(app.TM5900Tab, 'numeric');
            app.EditField_5.Limits = [-180 180];
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_6 = uieditfield(app.TM5900Tab, 'numeric');
            app.EditField_6.Limits = [-270 270];
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.Joint3Slider_5Label = uilabel(app.TM5900Tab);
            app.Joint3Slider_5Label.Text = 'Joint 3';
            app.Joint3Slider_5 = uislider(app.TM5900Tab);
            app.Joint3Slider_5.Limits = [-155 155];
            app.Joint3Slider_5.ValueChangedFcn = createCallbackFcn(app, @Joint3Slider_5ValueChanged, true);
            app.StatusLampLabel = uilabel(app.TM5900Tab);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.TM5900Tab);
            app.TeachButton_7 = uibutton(app.TM5900Tab, 'push');
            app.TeachButton_7.ButtonPushedFcn = createCallbackFcn(app, @TeachButton_7Pushed, true);
            app.TeachButton_7.Text = 'Teach';
            app.PickLocationLabel_2 = uilabel(app.TM5900Tab);
            app.PickLocationLabel_2.Text = 'Pick Location';
            app.PlaceLocationLabel_2 = uilabel(app.TM5900Tab);
            app.PlaceLocationLabel_2.Text = 'Place Location';
            app.xEditField_3Label = uilabel(app.TM5900Tab);
            app.xEditField_3Label.Text = 'x';
            app.xEditField_3 = uieditfield(app.TM5900Tab, 'numeric');
            app.xEditField_3.Limits = [-0.83 0.83];
            app.yEditField_3Label = uilabel(app.TM5900Tab);
            app.yEditField_3Label.Text = 'y';
            app.yEditField_3 = uieditfield(app.TM5900Tab, 'numeric');
            app.yEditField_3.Limits = [-0.57 0.57];
            app.zEditField_3Label = uilabel(app.TM5900Tab);
            app.zEditField_3Label.Text = 'z';
            app.zEditField_3 = uieditfield(app.TM5900Tab, 'numeric');
            app.zEditField_3.Limits = [-0.449 0.449];
            app.xEditField_4Label = uilabel(app.TM5900Tab);
            app.xEditField_4Label.Text = 'x';
            app.xEditField_4 = uieditfield(app.TM5900Tab, 'numeric');
            app.xEditField_4.Limits = [-0.833 0.833];
            app.yEditField_4Label = uilabel(app.TM5900Tab);
            app.yEditField_4Label.Text = 'y';
            app.yEditField_4 = uieditfield(app.TM5900Tab, 'numeric');
            app.yEditField_4.Limits = [-0.57 0.57];
            app.zEditField_4Label = uilabel(app.TM5900Tab);
            app.zEditField_4Label.Text = 'z';
            app.zEditField_4 = uieditfield(app.TM5900Tab, 'numeric');
            app.zEditField_4.Limits = [-0.449 0.449];
            app.ResumeButton = uibutton(app.TM5900Tab, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.UR3Tab = uitab(app.TabGroup);
            app.UR3Tab.Title = 'UR3';
            app.UR3ControlLabel = uilabel(app.UR3Tab);
            app.UR3ControlLabel.Text = 'UR3 Control';
            app.HomeButton_7 = uibutton(app.UR3Tab, 'push');
            app.HomeButton_7.ButtonPushedFcn = createCallbackFcn(app, @HomeButton_7Pushed, true);
            app.HomeButton_7.Text = 'Home';
            app.EStopButton = uibutton(app.UR3Tab, 'push');
            app.EStopButton.ButtonPushedFcn = createCallbackFcn(app, @EStopButtonPushed, true);
            app.EStopButton.Text = 'E-Stop';
            app.EditField_8 = uieditfield(app.UR3Tab, 'numeric');
            app.EditField_8.Limits = [-40 -0.01];
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @EditField_8ValueChanged, true);
            app.EditField_8.Value = -0.01;
            app.EditField_9 = uieditfield(app.UR3Tab, 'numeric');
            app.EditField_9.Limits = [-360 360];
            app.EditField_9.ValueChangedFcn = createCallbackFcn(app, @EditField_9ValueChanged, true);
            app.EditField_10 = uieditfield(app.UR3Tab, 'numeric');
            app.EditField_10.Limits = [-90 90];
            app.EditField_10.ValueChangedFcn = createCallbackFcn(app, @EditField_10ValueChanged, true);
            app.EditField_11 = uieditfield(app.UR3Tab, 'numeric');
            app.EditField_11.Limits = [-170 170];
            app.EditField_11.ValueChangedFcn = createCallbackFcn(app, @EditField_11ValueChanged, true);
            app.EditField_12 = uieditfield(app.UR3Tab, 'numeric');
            app.EditField_12.Limits = [-360 360];
            app.EditField_12.ValueChangedFcn = createCallbackFcn(app, @EditField_12ValueChanged, true);
            app.EditField_13 = uieditfield(app.UR3Tab, 'numeric');
            app.EditField_13.Limits = [-360 360];
            app.EditField_13.ValueChangedFcn = createCallbackFcn(app, @EditField_13ValueChanged, true);
            app.RailSliderLabel = uilabel(app.UR3Tab);
            app.RailSliderLabel.Text = 'Rail';
            app.RailSlider = uislider(app.UR3Tab);
            app.RailSlider.Limits = [-40 -0.01];
            app.RailSlider.ValueChangedFcn = createCallbackFcn(app, @RailSliderValueChanged, true);
            app.RailSlider.Value = -0.01;
            app.Joint1Slider_5Label = uilabel(app.UR3Tab);
            app.Joint1Slider_5Label.Text = 'Joint 1';
            app.Joint1Slider_5 = uislider(app.UR3Tab);
            app.Joint1Slider_5.Limits = [-360 360];
            app.Joint1Slider_5.ValueChangedFcn = createCallbackFcn(app, @Joint1Slider_5ValueChanged, true);
            app.Joint3Slider_6Label = uilabel(app.UR3Tab);
            app.Joint3Slider_6Label.Text = 'Joint 3';
            app.Joint3Slider_6 = uislider(app.UR3Tab);
            app.Joint3Slider_6.Limits = [-170 170];
            app.Joint3Slider_6.ValueChangedFcn = createCallbackFcn(app, @Joint3Slider_6ValueChanged, true);
            app.Joint4Slider_4Label = uilabel(app.UR3Tab);
            app.Joint4Slider_4Label.Text = 'Joint 4';
            app.Joint4Slider_4 = uislider(app.UR3Tab);
            app.Joint4Slider_4.Limits = [-360 360];
            app.Joint4Slider_4.ValueChangedFcn = createCallbackFcn(app, @Joint4Slider_4ValueChanged, true);
            app.Joint5Slider_4Label = uilabel(app.UR3Tab);
            app.Joint5Slider_4Label.Text = 'Joint 5';
            app.Joint5Slider_4 = uislider(app.UR3Tab);
            app.Joint5Slider_4.Limits = [-360 360];
            app.Joint5Slider_4.ValueChangedFcn = createCallbackFcn(app, @Joint5Slider_4ValueChanged, true);
            app.Joint2Slider_4Label = uilabel(app.UR3Tab);
            app.Joint2Slider_4Label.Text = 'Joint 2';
            app.Joint2Slider_4 = uislider(app.UR3Tab);
            app.Joint2Slider_4.Limits = [-90 90];
            app.Joint2Slider_4.ValueChangedFcn = createCallbackFcn(app, @Joint2Slider_4ValueChanged, true);
            app.EditField_15 = uieditfield(app.UR3Tab, 'numeric');
            app.EditField_15.Limits = [-360 360];
            app.EditField_15.ValueChangedFcn = createCallbackFcn(app, @EditField_15ValueChanged, true);
            app.Joint6Slider_4Label = uilabel(app.UR3Tab);
            app.Joint6Slider_4Label.Text = 'Joint 6';
            app.Joint6Slider_4 = uislider(app.UR3Tab);
            app.Joint6Slider_4.Limits = [-360 360];
            app.Joint6Slider_4.ValueChangedFcn = createCallbackFcn(app, @Joint6Slider_4ValueChanged, true);
            app.StatusLamp_2Label = uilabel(app.UR3Tab);
            app.StatusLamp_2Label.Text = 'Status';
            app.StatusLamp_2 = uilamp(app.UR3Tab);
            app.TeachButton_8 = uibutton(app.UR3Tab, 'push');
            app.TeachButton_8.ButtonPushedFcn = createCallbackFcn(app, @TeachButton_8Pushed, true);
            app.TeachButton_8.Text = 'Teach';
            app.PickLocationLabel_3 = uilabel(app.UR3Tab);
            app.PickLocationLabel_3.Text = 'Pick Location';
            app.PlaceLocationLabel_3 = uilabel(app.UR3Tab);
            app.PlaceLocationLabel_3.Text = 'Place Location';
            app.xEditField_5Label = uilabel(app.UR3Tab);
            app.xEditField_5Label.Text = 'x';
            app.xEditField_5 = uieditfield(app.UR3Tab, 'numeric');
            app.xEditField_5.Limits = [-0.83 0.83];
            app.yEditField_5Label = uilabel(app.UR3Tab);
            app.yEditField_5Label.Text = 'y';
            app.yEditField_5 = uieditfield(app.UR3Tab, 'numeric');
            app.yEditField_5.Limits = [-0.57 0.57];
            app.zEditField_5Label = uilabel(app.UR3Tab);
            app.zEditField_5Label.Text = 'z';
            app.zEditField_5 = uieditfield(app.UR3Tab, 'numeric');
            app.zEditField_5.Limits = [-0.449 0.449];
            app.xEditField_6Label = uilabel(app.UR3Tab);
            app.xEditField_6Label.Text = 'x';
            app.xEditField_6 = uieditfield(app.UR3Tab, 'numeric');
            app.xEditField_6.Limits = [-0.83 0.83];
            app.yEditField_6Label = uilabel(app.UR3Tab);
            app.yEditField_6Label.Text = 'y';
            app.yEditField_6 = uieditfield(app.UR3Tab, 'numeric');
            app.yEditField_6.Limits = [-0.57 0.57];
            app.zEditField_6Label = uilabel(app.UR3Tab);
            app.zEditField_6Label.Text = 'z';
            app.zEditField_6 = uieditfield(app.UR3Tab, 'numeric');
            app.zEditField_6.Limits = [-0.449 0.449];
            app.ResumeButton_2 = uibutton(app.UR3Tab, 'push');
            app.ResumeButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResumeButton_2Pushed, true);
            app.ResumeButton_2.Text = 'Resume';
            app.ControlTab = uitab(app.TabGroup);
            app.ControlTab.Title = 'Control';
            app.STARTSIMULATIONButton = uibutton(app.ControlTab, 'push');
            app.STARTSIMULATIONButton.ButtonPushedFcn = createCallbackFcn(app, @STARTSIMULATIONButtonPushed, true);
            app.STARTSIMULATIONButton.Text = 'START SIMULATION';
            app.ESTOPButton = uibutton(app.ControlTab, 'state');
            app.ESTOPButton.ValueChangedFcn = createCallbackFcn(app, @ESTOPButtonValueChanged, true);
            app.ESTOPButton.Text = 'ESTOP';
            app.ResumeButton_3 = uibutton(app.ControlTab, 'push');
            app.ResumeButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResumeButton_3Pushed, true);
            app.ResumeButton_3.Text = 'Resume';
            app.StatusLamp_3Label = uilabel(app.ControlTab);
            app.StatusLamp_3Label.Text = 'Status';
            app.StatusLamp_3 = uilamp(app.ControlTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end