classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        TM5900Tab             matlab.ui.container.Tab
        ResumeButton          matlab.ui.control.Button
        zEditField_4          matlab.ui.control.NumericEditField
        zEditField_4Label     matlab.ui.control.Label
        yEditField_4          matlab.ui.control.NumericEditField
        yEditField_4Label     matlab.ui.control.Label
        xEditField_4          matlab.ui.control.NumericEditField
        xEditField_4Label     matlab.ui.control.Label
        zEditField_3          matlab.ui.control.NumericEditField
        zEditField_3Label     matlab.ui.control.Label
        yEditField_3          matlab.ui.control.NumericEditField
        yEditField_3Label     matlab.ui.control.Label
        xEditField_3          matlab.ui.control.NumericEditField
        xEditField_3Label     matlab.ui.control.Label
        PlaceLocationLabel_2  matlab.ui.control.Label
        PickLocationLabel_2   matlab.ui.control.Label
        TeachButton_7         matlab.ui.control.Button
        StatusLamp            matlab.ui.control.Lamp
        StatusLampLabel       matlab.ui.control.Label
        Joint3Slider_5        matlab.ui.control.Slider
        Joint3Slider_5Label   matlab.ui.control.Label
        EditField_7           matlab.ui.control.NumericEditField
        EditField_6           matlab.ui.control.NumericEditField
        EditField_5           matlab.ui.control.NumericEditField
        EditField_4           matlab.ui.control.NumericEditField
        EditField_3           matlab.ui.control.NumericEditField
        EditField_2           matlab.ui.control.NumericEditField
        EditField             matlab.ui.control.NumericEditField
        EStopButton_2         matlab.ui.control.Button
        GripperSlider_3       matlab.ui.control.Slider
        GripperSlider_3Label  matlab.ui.control.Label
        Joint6Slider_3        matlab.ui.control.Slider
        Joint6Slider_3Label   matlab.ui.control.Label
        Joint5Slider_3        matlab.ui.control.Slider
        Joint5Slider_3Label   matlab.ui.control.Label
        Joint4Slider_3        matlab.ui.control.Slider
        Joint4Slider_3Label   matlab.ui.control.Label
        Joint2Slider_3        matlab.ui.control.Slider
        Joint2Slider_3Label   matlab.ui.control.Label
        Joint1Slider_3        matlab.ui.control.Slider
        Joint1Slider_3Label   matlab.ui.control.Label
        HomeButton_6          matlab.ui.control.Button
        TM5900ControlLabel    matlab.ui.control.Label
        UR3Tab                matlab.ui.container.Tab
        ResumeButton_2        matlab.ui.control.Button
        zEditField_6          matlab.ui.control.NumericEditField
        zEditField_6Label     matlab.ui.control.Label
        yEditField_6          matlab.ui.control.NumericEditField
        yEditField_6Label     matlab.ui.control.Label
        xEditField_6          matlab.ui.control.NumericEditField
        xEditField_6Label     matlab.ui.control.Label
        zEditField_5          matlab.ui.control.NumericEditField
        zEditField_5Label     matlab.ui.control.Label
        yEditField_5          matlab.ui.control.NumericEditField
        yEditField_5Label     matlab.ui.control.Label
        xEditField_5          matlab.ui.control.NumericEditField
        xEditField_5Label     matlab.ui.control.Label
        PlaceLocationLabel_3  matlab.ui.control.Label
        PickLocationLabel_3   matlab.ui.control.Label
        TeachButton_8         matlab.ui.control.Button
        StatusLamp_2          matlab.ui.control.Lamp
        StatusLamp_2Label     matlab.ui.control.Label
        Joint6Slider_4        matlab.ui.control.Slider
        Joint6Slider_4Label   matlab.ui.control.Label
        EditField_15          matlab.ui.control.NumericEditField
        Joint2Slider_4        matlab.ui.control.Slider
        Joint2Slider_4Label   matlab.ui.control.Label
        GripperSlider_4       matlab.ui.control.Slider
        GripperSlider_4Label  matlab.ui.control.Label
        Joint5Slider_4        matlab.ui.control.Slider
        Joint5Slider_4Label   matlab.ui.control.Label
        Joint4Slider_4        matlab.ui.control.Slider
        Joint4Slider_4Label   matlab.ui.control.Label
        Joint3Slider_6        matlab.ui.control.Slider
        Joint3Slider_6Label   matlab.ui.control.Label
        Joint1Slider_5        matlab.ui.control.Slider
        Joint1Slider_5Label   matlab.ui.control.Label
        RailSlider            matlab.ui.control.Slider
        RailSliderLabel       matlab.ui.control.Label
        EditField_14          matlab.ui.control.NumericEditField
        EditField_13          matlab.ui.control.NumericEditField
        EditField_12          matlab.ui.control.NumericEditField
        EditField_11          matlab.ui.control.NumericEditField
        EditField_10          matlab.ui.control.NumericEditField
        EditField_9           matlab.ui.control.NumericEditField
        EditField_8           matlab.ui.control.NumericEditField
        EStopButton           matlab.ui.control.Button
        HomeButton_7          matlab.ui.control.Button
        UR3ControlLabel       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Joint1Slider_3
        function Joint1Slider_3ValueChanged(app, event)
            value = app.Joint1Slider_3.Value;
            app.EditField.Value = value;
        end

        % Value changed function: Joint2Slider_3
        function Joint2Slider_3ValueChanged(app, event)
            value = app.Joint2Slider_3.Value;
            app.EditField_2.Value = value;
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.Joint1Slider_3.Value = value;
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.Joint2Slider_3.Value = value;
        end

        % Value changed function: Joint3Slider_5
        function Joint3Slider_5ValueChanged(app, event)
            value = app.Joint3Slider_5.Value;
            app.EditField_3.Value = value;
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            app.Joint3Slider_5.Value = value;
        end

        % Value changed function: Joint4Slider_3
        function Joint4Slider_3ValueChanged(app, event)
            value = app.Joint4Slider_3.Value;
            app.EditField_4.Value = value;
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            app.Joint4Slider_3.Value = value;
        end

        % Value changed function: Joint5Slider_3
        function Joint5Slider_3ValueChanged(app, event)
            value = app.Joint5Slider_3.Value;
            app.EditField_5.Value = value;
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
            app.Joint5Slider_3.Value = value;
        end

        % Value changed function: Joint6Slider_3
        function Joint6Slider_3ValueChanged(app, event)
            value = app.Joint6Slider_3.Value;
            app.EditField_6.Value = value;
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            value = app.EditField_6.Value;
            app.Joint6Slider_3.Value = value;
        end

        % Value changed function: GripperSlider_3
        function GripperSlider_3ValueChanged(app, event)
            value = app.GripperSlider_3.Value;
            app.EditField_7.Value = value;
        end

        % Value changed function: EditField_7
        function EditField_7ValueChanged(app, event)
            value = app.EditField_7.Value;
            app.GripperSlider_3.Value = value;
        end

        % Button pushed function: HomeButton_6
        function HomeButton_6Pushed(app, event)
            app.EditField.Value = 0;
            app.Joint1Slider_3.Value = 0;
            app.EditField_2.Value = 0;
            app.Joint2Slider_3.Value = 0;
            app.EditField_3.Value = 0;
            app.Joint3Slider_5.Value = 0;
            app.EditField_4.Value = 0;
            app.Joint4Slider_3.Value = 0;
            app.EditField_5.Value = 0;
            app.Joint5Slider_3.Value = 0;
            app.EditField_6.Value = 0;
            app.Joint6Slider_3.Value = 0;
            app.EditField_7.Value = 0;
            app.GripperSlider_3.Value = 0;
        end

        % Button pushed function: EStopButton_2
        function EStopButton_2Pushed(app, event)
            app.StatusLamp.Color = 'red';
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            app.StatusLamp.Color = 'green';
        end

        % Value changed function: RailSlider
        function RailSliderValueChanged(app, event)
            value = app.RailSlider.Value;
            app.EditField_8.Value = value;
        end

        % Value changed function: EditField_8
        function EditField_8ValueChanged(app, event)
            value = app.EditField_8.Value;
            app.RailSlider.Value = value;
        end

        % Value changed function: Joint1Slider_5
        function Joint1Slider_5ValueChanged(app, event)
            value = app.Joint1Slider_5.Value;
            app.EditField_9.Value = value;
        end

        % Value changed function: EditField_9
        function EditField_9ValueChanged(app, event)
            value = app.EditField_9.Value;
            app.Joint1Slider_5.Value = value;
        end

        % Value changed function: Joint2Slider_4
        function Joint2Slider_4ValueChanged(app, event)
            value = app.Joint2Slider_4.Value;
            app.EditField_10.Value = value;
        end

        % Value changed function: EditField_10
        function EditField_10ValueChanged(app, event)
            value = app.EditField_10.Value;
            app.Joint2Slider_4.Value = value;
        end

        % Value changed function: Joint3Slider_6
        function Joint3Slider_6ValueChanged(app, event)
            value = app.Joint3Slider_6.Value;
            app.EditField_11.Value = value;
        end

        % Value changed function: EditField_11
        function EditField_11ValueChanged(app, event)
            value = app.EditField_11.Value;
            app.Joint3Slider_6.Value = value;
        end

        % Value changed function: Joint4Slider_4
        function Joint4Slider_4ValueChanged(app, event)
            value = app.Joint4Slider_4.Value;
            app.EditField_12.Value = value;
        end

        % Value changed function: EditField_12
        function EditField_12ValueChanged(app, event)
            value = app.EditField_12.Value;
            app.Joint4Slider_4.Value = value;
        end

        % Value changed function: Joint5Slider_4
        function Joint5Slider_4ValueChanged(app, event)
            value = app.Joint5Slider_4.Value;
            app.EditField_13.Value = value;
        end

        % Value changed function: EditField_13
        function EditField_13ValueChanged(app, event)
            value = app.EditField_13.Value;
            app.Joint5Slider_4.Value = value;
        end

        % Value changed function: Joint6Slider_4
        function Joint6Slider_4ValueChanged(app, event)
            value = app.Joint6Slider_4.Value;
            app.EditField_15.Value = value;
        end

        % Value changed function: EditField_15
        function EditField_15ValueChanged(app, event)
            value = app.EditField_15.Value;
            app.Joint6Slider_4.Value = value;
        end

        % Value changed function: GripperSlider_4
        function GripperSlider_4ValueChanged(app, event)
            value = app.GripperSlider_4.Value;
            app.EditField_14.Value = value;
        end

        % Value changed function: EditField_14
        function EditField_14ValueChanged(app, event)
            value = app.EditField_14.Value;
            app.GripperSlider_4.Value = value;
        end

        % Button pushed function: HomeButton_7
        function HomeButton_7Pushed(app, event)
            app.EditField_8.Value = -0.01;
            app.RailSlider.Value = -0.01;
            app.EditField_9.Value = 0;
            app.Joint1Slider_5.Value = 0;
            app.EditField_10.Value = 0;
            app.Joint2Slider_4.Value = 0;
            app.EditField_11.Value = 0;
            app.Joint3Slider_6.Value = 0;
            app.EditField_12.Value = 0;
            app.Joint4Slider_4.Value = 0;
            app.EditField_13.Value = 0;
            app.Joint5Slider_4.Value = 0;
            app.EditField_15.Value = 0;
            app.Joint6Slider_4.Value = 0;
            app.EditField_14.Value = 0;
            app.GripperSlider_4.Value = 0;
        end

        % Button pushed function: EStopButton
        function EStopButtonPushed(app, event)
            app.StatusLamp_2.Color = 'red';
        end

        % Button pushed function: ResumeButton_2
        function ResumeButton_2Pushed(app, event)
            app.StatusLamp_2.Color = 'green';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TM5900Tab = uitab(app.TabGroup);
            app.TM5900Tab.Title = 'TM5-900';
            app.TM5900ControlLabel = uilabel(app.TM5900Tab);
            app.TM5900ControlLabel.Text = 'TM5-900 Control';
            app.HomeButton_6 = uibutton(app.TM5900Tab, 'push');
            app.HomeButton_6.ButtonPushedFcn = createCallbackFcn(app, @HomeButton_6Pushed, true);
            app.HomeButton_6.Text = 'Home';
            app.Joint1Slider_3Label = uilabel(app.TM5900Tab);
            app.Joint1Slider_3Label.Text = 'Joint 1';
            app.Joint1Slider_3 = uislider(app.TM5900Tab);
            app.Joint1Slider_3.Limits = [-270 270];
            app.Joint1Slider_3.ValueChangedFcn = createCallbackFcn(app, @Joint1Slider_3ValueChanged, true);
            app.Joint2Slider_3Label = uilabel(app.TM5900Tab);
            app.Joint2Slider_3Label.Text = 'Joint 2';
            app.Joint2Slider_3 = uislider(app.TM5900Tab);
            app.Joint2Slider_3.Limits = [-180 180];
            app.Joint2Slider_3.ValueChangedFcn = createCallbackFcn(app, @Joint2Slider_3ValueChanged, true);
            app.Joint4Slider_3Label = uilabel(app.TM5900Tab);
            app.Joint4Slider_3Label.Text = 'Joint 4';
            app.Joint4Slider_3 = uislider(app.TM5900Tab);
            app.Joint4Slider_3.Limits = [-180 180];
            app.Joint4Slider_3.ValueChangedFcn = createCallbackFcn(app, @Joint4Slider_3ValueChanged, true);
            app.Joint5Slider_3Label = uilabel(app.TM5900Tab);
            app.Joint5Slider_3Label.Text = 'Joint 5';
            app.Joint5Slider_3 = uislider(app.TM5900Tab);
            app.Joint5Slider_3.Limits = [-180 180];
            app.Joint5Slider_3.ValueChangedFcn = createCallbackFcn(app, @Joint5Slider_3ValueChanged, true);
            app.Joint6Slider_3Label = uilabel(app.TM5900Tab);
            app.Joint6Slider_3Label.Text = 'Joint 6';
            app.Joint6Slider_3 = uislider(app.TM5900Tab);
            app.Joint6Slider_3.Limits = [-270 270];
            app.Joint6Slider_3.ValueChangedFcn = createCallbackFcn(app, @Joint6Slider_3ValueChanged, true);
            app.GripperSlider_3Label = uilabel(app.TM5900Tab);
            app.GripperSlider_3Label.Text = 'Gripper';
            app.GripperSlider_3 = uislider(app.TM5900Tab);
            app.GripperSlider_3.ValueChangedFcn = createCallbackFcn(app, @GripperSlider_3ValueChanged, true);
            app.EStopButton_2 = uibutton(app.TM5900Tab, 'push');
            app.EStopButton_2.ButtonPushedFcn = createCallbackFcn(app, @EStopButton_2Pushed, true);
            app.EStopButton_2.Text = 'E-Stop';
            app.EditField = uieditfield(app.TM5900Tab, 'numeric');
            app.EditField.Limits = [-270 270];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.TM5900Tab, 'numeric');
            app.EditField_2.Limits = [-180 180];
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3 = uieditfield(app.TM5900Tab, 'numeric');
            app.EditField_3.Limits = [-155 155];
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_4 = uieditfield(app.TM5900Tab, 'numeric');
            app.EditField_4.Limits = [-180 180];
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_5 = uieditfield(app.TM5900Tab, 'numeric');
            app.EditField_5.Limits = [-180 180];
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_6 = uieditfield(app.TM5900Tab, 'numeric');
            app.EditField_6.Limits = [-270 270];
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EditField_7 = uieditfield(app.TM5900Tab, 'numeric');
            app.EditField_7.Limits = [0 100];
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.Joint3Slider_5Label = uilabel(app.TM5900Tab);
            app.Joint3Slider_5Label.Text = 'Joint 3';
            app.Joint3Slider_5 = uislider(app.TM5900Tab);
            app.Joint3Slider_5.Limits = [-155 155];
            app.Joint3Slider_5.ValueChangedFcn = createCallbackFcn(app, @Joint3Slider_5ValueChanged, true);
            app.StatusLampLabel = uilabel(app.TM5900Tab);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.TM5900Tab);
            app.TeachButton_7 = uibutton(app.TM5900Tab, 'push');
            app.TeachButton_7.Text = 'Teach';
            app.PickLocationLabel_2 = uilabel(app.TM5900Tab);
            app.PickLocationLabel_2.Text = 'Pick Location';
            app.PlaceLocationLabel_2 = uilabel(app.TM5900Tab);
            app.PlaceLocationLabel_2.Text = 'Place Location';
            app.xEditField_3Label = uilabel(app.TM5900Tab);
            app.xEditField_3Label.Text = 'x';
            app.xEditField_3 = uieditfield(app.TM5900Tab, 'numeric');
            app.xEditField_3.Limits = [-0.83 0.83];
            app.yEditField_3Label = uilabel(app.TM5900Tab);
            app.yEditField_3Label.Text = 'y';
            app.yEditField_3 = uieditfield(app.TM5900Tab, 'numeric');
            app.yEditField_3.Limits = [-0.57 0.57];
            app.zEditField_3Label = uilabel(app.TM5900Tab);
            app.zEditField_3Label.Text = 'z';
            app.zEditField_3 = uieditfield(app.TM5900Tab, 'numeric');
            app.zEditField_3.Limits = [-0.449 0.449];
            app.xEditField_4Label = uilabel(app.TM5900Tab);
            app.xEditField_4Label.Text = 'x';
            app.xEditField_4 = uieditfield(app.TM5900Tab, 'numeric');
            app.xEditField_4.Limits = [-0.833 0.833];
            app.yEditField_4Label = uilabel(app.TM5900Tab);
            app.yEditField_4Label.Text = 'y';
            app.yEditField_4 = uieditfield(app.TM5900Tab, 'numeric');
            app.yEditField_4.Limits = [-0.57 0.57];
            app.zEditField_4Label = uilabel(app.TM5900Tab);
            app.zEditField_4Label.Text = 'z';
            app.zEditField_4 = uieditfield(app.TM5900Tab, 'numeric');
            app.zEditField_4.Limits = [-0.449 0.449];
            app.ResumeButton = uibutton(app.TM5900Tab, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.UR3Tab = uitab(app.TabGroup);
            app.UR3Tab.Title = 'UR3';
            app.UR3ControlLabel = uilabel(app.UR3Tab);
            app.UR3ControlLabel.Text = 'UR3 Control';
            app.HomeButton_7 = uibutton(app.UR3Tab, 'push');
            app.HomeButton_7.ButtonPushedFcn = createCallbackFcn(app, @HomeButton_7Pushed, true);
            app.HomeButton_7.Text = 'Home';
            app.EStopButton = uibutton(app.UR3Tab, 'push');
            app.EStopButton.ButtonPushedFcn = createCallbackFcn(app, @EStopButtonPushed, true);
            app.EStopButton.Text = 'E-Stop';
            app.EditField_8 = uieditfield(app.UR3Tab, 'numeric');
            app.EditField_8.Limits = [-0.8 -0.01];
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @EditField_8ValueChanged, true);
            app.EditField_8.Value = -0.01;
            app.EditField_9 = uieditfield(app.UR3Tab, 'numeric');
            app.EditField_9.Limits = [-360 360];
            app.EditField_9.ValueChangedFcn = createCallbackFcn(app, @EditField_9ValueChanged, true);
            app.EditField_10 = uieditfield(app.UR3Tab, 'numeric');
            app.EditField_10.Limits = [-90 90];
            app.EditField_10.ValueChangedFcn = createCallbackFcn(app, @EditField_10ValueChanged, true);
            app.EditField_11 = uieditfield(app.UR3Tab, 'numeric');
            app.EditField_11.Limits = [-170 170];
            app.EditField_11.ValueChangedFcn = createCallbackFcn(app, @EditField_11ValueChanged, true);
            app.EditField_12 = uieditfield(app.UR3Tab, 'numeric');
            app.EditField_12.Limits = [-360 360];
            app.EditField_12.ValueChangedFcn = createCallbackFcn(app, @EditField_12ValueChanged, true);
            app.EditField_13 = uieditfield(app.UR3Tab, 'numeric');
            app.EditField_13.Limits = [-360 360];
            app.EditField_13.ValueChangedFcn = createCallbackFcn(app, @EditField_13ValueChanged, true);
            app.EditField_14 = uieditfield(app.UR3Tab, 'numeric');
            app.EditField_14.Limits = [0 100];
            app.EditField_14.ValueChangedFcn = createCallbackFcn(app, @EditField_14ValueChanged, true);
            app.RailSliderLabel = uilabel(app.UR3Tab);
            app.RailSliderLabel.Text = 'Rail';
            app.RailSlider = uislider(app.UR3Tab);
            app.RailSlider.Limits = [-0.8 -0.01];
            app.RailSlider.ValueChangedFcn = createCallbackFcn(app, @RailSliderValueChanged, true);
            app.RailSlider.Value = -0.01;
            app.Joint1Slider_5Label = uilabel(app.UR3Tab);
            app.Joint1Slider_5Label.Text = 'Joint 1';
            app.Joint1Slider_5 = uislider(app.UR3Tab);
            app.Joint1Slider_5.Limits = [-360 360];
            app.Joint1Slider_5.ValueChangedFcn = createCallbackFcn(app, @Joint1Slider_5ValueChanged, true);
            app.Joint3Slider_6Label = uilabel(app.UR3Tab);
            app.Joint3Slider_6Label.Text = 'Joint 3';
            app.Joint3Slider_6 = uislider(app.UR3Tab);
            app.Joint3Slider_6.Limits = [-170 170];
            app.Joint3Slider_6.ValueChangedFcn = createCallbackFcn(app, @Joint3Slider_6ValueChanged, true);
            app.Joint4Slider_4Label = uilabel(app.UR3Tab);
            app.Joint4Slider_4Label.Text = 'Joint 4';
            app.Joint4Slider_4 = uislider(app.UR3Tab);
            app.Joint4Slider_4.Limits = [-360 360];
            app.Joint4Slider_4.ValueChangedFcn = createCallbackFcn(app, @Joint4Slider_4ValueChanged, true);
            app.Joint5Slider_4Label = uilabel(app.UR3Tab);
            app.Joint5Slider_4Label.Text = 'Joint 5';
            app.Joint5Slider_4 = uislider(app.UR3Tab);
            app.Joint5Slider_4.Limits = [-360 360];
            app.Joint5Slider_4.ValueChangedFcn = createCallbackFcn(app, @Joint5Slider_4ValueChanged, true);
            app.GripperSlider_4Label = uilabel(app.UR3Tab);
            app.GripperSlider_4Label.Text = 'Gripper';
            app.GripperSlider_4 = uislider(app.UR3Tab);
            app.GripperSlider_4.ValueChangedFcn = createCallbackFcn(app, @GripperSlider_4ValueChanged, true);
            app.Joint2Slider_4Label = uilabel(app.UR3Tab);
            app.Joint2Slider_4Label.Text = 'Joint 2';
            app.Joint2Slider_4 = uislider(app.UR3Tab);
            app.Joint2Slider_4.Limits = [-90 90];
            app.Joint2Slider_4.ValueChangedFcn = createCallbackFcn(app, @Joint2Slider_4ValueChanged, true);
            app.EditField_15 = uieditfield(app.UR3Tab, 'numeric');
            app.EditField_15.Limits = [-360 360];
            app.EditField_15.ValueChangedFcn = createCallbackFcn(app, @EditField_15ValueChanged, true);
            app.Joint6Slider_4Label = uilabel(app.UR3Tab);
            app.Joint6Slider_4Label.Text = 'Joint 6';
            app.Joint6Slider_4 = uislider(app.UR3Tab);
            app.Joint6Slider_4.Limits = [-360 360];
            app.Joint6Slider_4.ValueChangedFcn = createCallbackFcn(app, @Joint6Slider_4ValueChanged, true);
            app.StatusLamp_2Label = uilabel(app.UR3Tab);
            app.StatusLamp_2Label.Text = 'Status';
            app.StatusLamp_2 = uilamp(app.UR3Tab);
            app.TeachButton_8 = uibutton(app.UR3Tab, 'push');
            app.TeachButton_8.Text = 'Teach';
            app.PickLocationLabel_3 = uilabel(app.UR3Tab);
            app.PickLocationLabel_3.Text = 'Pick Location';
            app.PlaceLocationLabel_3 = uilabel(app.UR3Tab);
            app.PlaceLocationLabel_3.Text = 'Place Location';
            app.xEditField_5Label = uilabel(app.UR3Tab);
            app.xEditField_5Label.Text = 'x';
            app.xEditField_5 = uieditfield(app.UR3Tab, 'numeric');
            app.xEditField_5.Limits = [-0.83 0.83];
            app.yEditField_5Label = uilabel(app.UR3Tab);
            app.yEditField_5Label.Text = 'y';
            app.yEditField_5 = uieditfield(app.UR3Tab, 'numeric');
            app.yEditField_5.Limits = [-0.57 0.57];
            app.zEditField_5Label = uilabel(app.UR3Tab);
            app.zEditField_5Label.Text = 'z';
            app.zEditField_5 = uieditfield(app.UR3Tab, 'numeric');
            app.zEditField_5.Limits = [-0.449 0.449];
            app.xEditField_6Label = uilabel(app.UR3Tab);
            app.xEditField_6Label.Text = 'x';
            app.xEditField_6 = uieditfield(app.UR3Tab, 'numeric');
            app.xEditField_6.Limits = [-0.83 0.83];
            app.yEditField_6Label = uilabel(app.UR3Tab);
            app.yEditField_6Label.Text = 'y';
            app.yEditField_6 = uieditfield(app.UR3Tab, 'numeric');
            app.yEditField_6.Limits = [-0.57 0.57];
            app.zEditField_6Label = uilabel(app.UR3Tab);
            app.zEditField_6Label.Text = 'z';
            app.zEditField_6 = uieditfield(app.UR3Tab, 'numeric');
            app.zEditField_6.Limits = [-0.449 0.449];
            app.ResumeButton_2 = uibutton(app.UR3Tab, 'push');
            app.ResumeButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResumeButton_2Pushed, true);
            app.ResumeButton_2.Text = 'Resume';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end